classdef PhonemeSpeechRecognition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpeechRecognizer         matlab.ui.Figure
        InstructionsLabel        matlab.ui.control.Label
        WordProfileButton        matlab.ui.control.Button
        SoundPlayingLabel        matlab.ui.control.Label
        CaseSelector             matlab.ui.control.DropDown
        AllTakesButton           matlab.ui.control.Button
        PotentialWordsOutput     matlab.ui.control.TextArea
        PotentialWordsLabel      matlab.ui.control.Label
        StartDetection           matlab.ui.control.Button
        PoweredByLabel           matlab.ui.control.Label
        MerriamWebsterImage      matlab.ui.control.Image
        CaseDropDownLabel        matlab.ui.control.Label
        WordProfileGraph         matlab.ui.control.UIAxes
        WordSpectrographDisplay  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        y % Frequency Data of Word Selected
        fs % Sampling Frequency
        fileName % Description
        averageSound % Description
        wordName % Description
        ValidWords % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CaseSelector
        function CaseSelectorValueChanged(app, event)
            app.AllTakesButton.Enable = false;
            app.WordProfileButton.Enable = false;
            app.StartDetection.Enable = false;
            
            value = app.CaseSelector.Value;
            switch value
                case "Select a Word"
                    app.AllTakesButton.Enable = false;
                    app.WordProfileButton.Enable = false;
                    title(app.WordSpectrographDisplay, 'Waiting for Case Selection');
                    title(app.WordProfileGraph,  'Choose a Case');
                case "Mud"
                    app.fileName = 'Words\Mud.mp3';
                    title(app.WordSpectrographDisplay, 'Mud (6 takes)');
                    title(app.WordProfileGraph,  'Mud (Average)');
                    app.wordName = "Mud";
                case "Cake"
                    app.fileName = 'Words\Cake.mp3';
                    title(app.WordSpectrographDisplay, 'Cake (6 takes)');
                    title(app.WordProfileGraph,  'Cake (Average)');
                    app.wordName = "Cake";
                case "Dog"
                    app.fileName = 'Words\Dog.mp3';
                    title(app.WordSpectrographDisplay, 'Dog (6 takes)');
                    title(app.WordProfileGraph,  'Dog (Average)');
                    app.wordName = "Dog";
            end
            
            if(value ~= "Select a Word")
                [app.y, app.fs] = audioread(app.fileName);
                app.AllTakesButton.Enable = true;
                t = 0:seconds(1/app.fs):seconds((length(app.y)-1)/app.fs);
                plot(app.WordSpectrographDisplay, t, app.y);
                app.averageSound = preProcessor(app.fileName);
                plot(app.WordProfileGraph, app.averageSound);
                app.WordProfileButton.Enable = true;
                app.StartDetection.Enable = true;
            end

        end

        % Button pushed function: AllTakesButton
        function AllTakesButtonPushed(app, event)
            clear sound
            sound(app.y, app.fs);
        end

        % Button pushed function: StartDetection
        function StartDetectionButtonPushed(app, event)
            app.StartDetection.Enable = false;
            app.StartDetection.Text = "Processing...";
            [possiblePSpellings, app.ValidWords] = MasterFunction2(app.wordName, app.fileName);
            app.PotentialWordsOutput.Value = app.ValidWords;
            app.StartDetection.Text = "Start Phoneme Detection";
            app.StartDetection.Enable = true;
        end

        % Button pushed function: WordProfileButton
        function WordProfileButtonPushed(app, event)
            clear sound
            sound(app.averageSound, app.fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpeechRecognizer = uifigure('Visible', 'off');
            app.SpeechRecognizer.Name = 'MATLAB App';
            app.WordSpectrographDisplay = uiaxes(app.SpeechRecognizer);
            title(app.WordSpectrographDisplay, 'Raw Word Data')
            xlabel(app.WordSpectrographDisplay, 'Time (seconds)')
            ylabel(app.WordSpectrographDisplay, 'Y')
            zlabel(app.WordSpectrographDisplay, 'Z')
            app.WordProfileGraph = uiaxes(app.SpeechRecognizer);
            title(app.WordProfileGraph, 'Word Profile')
            xlabel(app.WordProfileGraph, 'X')
            ylabel(app.WordProfileGraph, 'Y')
            zlabel(app.WordProfileGraph, 'Z')
            app.CaseDropDownLabel = uilabel(app.SpeechRecognizer);
            app.CaseDropDownLabel.Text = 'Case';
            app.MerriamWebsterImage = uiimage(app.SpeechRecognizer);
            app.MerriamWebsterImage.ImageSource = 'MerriamWebsterLogo.png';
            app.PoweredByLabel = uilabel(app.SpeechRecognizer);
            app.PoweredByLabel.Text = 'Powered By:';
            app.StartDetection = uibutton(app.SpeechRecognizer, 'push');
            app.StartDetection.ButtonPushedFcn = createCallbackFcn(app, @StartDetectionButtonPushed, true);
            app.StartDetection.Enable = 'off';
            app.StartDetection.Text = 'Start Phoneme Detection';
            app.PotentialWordsLabel = uilabel(app.SpeechRecognizer);
            app.PotentialWordsLabel.Text = 'Potential Words:';
            app.PotentialWordsOutput = uitextarea(app.SpeechRecognizer);
            app.AllTakesButton = uibutton(app.SpeechRecognizer, 'push');
            app.AllTakesButton.ButtonPushedFcn = createCallbackFcn(app, @AllTakesButtonPushed, true);
            app.AllTakesButton.Enable = 'off';
            app.AllTakesButton.Text = 'All Takes';
            app.CaseSelector = uidropdown(app.SpeechRecognizer);
            app.CaseSelector.Items = {'Select a Word', 'Mud', 'Cake', 'Dog'};
            app.CaseSelector.ItemsData = {'Select a Word', 'Mud', 'Cake', 'Dog'};
            app.CaseSelector.ValueChangedFcn = createCallbackFcn(app, @CaseSelectorValueChanged, true);
            app.CaseSelector.Placeholder = 'CaseSelector';
            app.CaseSelector.Value = 'Select a Word';
            app.SoundPlayingLabel = uilabel(app.SpeechRecognizer);
            app.SoundPlayingLabel.Text = 'Listen to the Sound:';
            app.WordProfileButton = uibutton(app.SpeechRecognizer, 'push');
            app.WordProfileButton.ButtonPushedFcn = createCallbackFcn(app, @WordProfileButtonPushed, true);
            app.WordProfileButton.Enable = 'off';
            app.WordProfileButton.Text = 'Word Profile';
            app.InstructionsLabel = uilabel(app.SpeechRecognizer);
            app.InstructionsLabel.Text = 'To Use: Select a word from the Case Dropdown below and listen to it. Then when ready start the phoneme detection process by clicking the blue button in the middle of the app.';
            % Show the figure after all components are created
            app.SpeechRecognizer.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PhonemeSpeechRecognition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpeechRecognizer)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpeechRecognizer)
        end
    end
end